classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TabGroupLeft             matlab.ui.container.TabGroup
        tb_setup                 matlab.ui.container.Tab
        txt_valves               matlab.ui.control.EditField
        ValuesLabel              matlab.ui.control.Label
        ConnectedLamp            matlab.ui.control.Lamp
        ConnectedLampLabel       matlab.ui.control.Label
        txt_runname              matlab.ui.control.EditField
        RunnameLabel             matlab.ui.control.Label
        EstimatedtotaltimeLabel  matlab.ui.control.Label
        txt_time_estimtotal      matlab.ui.control.Label
        na_baud                  matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel   matlab.ui.control.Label
        txa_port                 matlab.ui.control.EditField
        PortEditFieldLabel       matlab.ui.control.Label
        btn_connect              matlab.ui.control.Button
        tb_runtime               matlab.ui.container.Tab
        ElapsedtimestepLabel     matlab.ui.control.Label
        txt_time_elapsed_step    matlab.ui.control.Label
        gauge_time               matlab.ui.control.LinearGauge
        totalLabel               matlab.ui.control.Label
        txt_time_elapsed         matlab.ui.control.Label
        PausedtimeLabel          matlab.ui.control.Label
        txt_time_paused          matlab.ui.control.Label
        EndtimeLabel             matlab.ui.control.Label
        txt_time_end             matlab.ui.control.Label
        txt_time_start           matlab.ui.control.Label
        StarttimeLabel           matlab.ui.control.Label
        txt_status               matlab.ui.control.Label
        OpenButton               matlab.ui.control.Button
        btn_pause                matlab.ui.control.Button
        txt_scriptfile_name      matlab.ui.control.EditField
        StartLabel_4             matlab.ui.control.Label
        txt_logfile_name         matlab.ui.control.EditField
        StartLabel_3             matlab.ui.control.Label
        running_lamp             matlab.ui.control.Lamp
        StartButton              matlab.ui.control.Button
        EndrunButton             matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        next_steps               matlab.ui.container.Tab
        btn_NextStepLoad         matlab.ui.control.Button
        NextLabel                matlab.ui.control.Label
        CurrentLabel             matlab.ui.control.Label
        ta_CurrentStep           matlab.ui.control.TextArea
        btn_EndCurrentStep       matlab.ui.control.Button
        ta_NextSteps             matlab.ui.control.TextArea
        btn_NextStepEdit         matlab.ui.control.Button
        prev_steps               matlab.ui.container.Tab
        ta_PrevSteps             matlab.ui.control.TextArea
        AddlognotesTab           matlab.ui.container.Tab
        btn_lognote_add          matlab.ui.control.Button
        cb_lognote_addtime       matlab.ui.control.CheckBox
        txa_lognote              matlab.ui.control.TextArea
        CommentTextAreaLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global runstatus runtime 
            runstatus = 'Setup';
            addpath('./Functions');
        end

        % Button pushed function: EndrunButton
        function EndrunButtonPushed(app, event)
            % Update buttons
            global maxtime runstatus thissteptime NextSteps
            runstatus = 'Cancelled';
            app.StartButton.Enable = true;
            app.EndrunButton.Enable = false
            app.txt_time_end.Text = datestr(now)
            app.txt_status.Text = runstatus;
            app.running_lamp.Color= [1.,0.,0.];          
            % end conditions
            thissteptime = 0;
            NextSteps = []
            % report end
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'), ...
                newline() + " <!!> Run cancelled (" + datestr(now) + ")" + newline());
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global maxtime runtime steptime runstatus NextSteps device thissteptime paused_time_log
            runstatus = 'Running';
            disp(" Run started at " + datestr(now));
            % Update buttons
            app.TabGroupLeft.SelectedTab = app.tb_runtime;
            app.StartButton.Enable = false;
            app.OpenButton.Enable = false;
            app.btn_connect.Enable = false;
            app.EndrunButton.Enable = true;
            app.btn_pause.Enable = true;
            % Text and indicators
            paused_time_log = 0;
            app.txt_time_start.Text = datestr(now);
            app.txt_time_end.Text = "(estim.) " + datestr(now+maxtime/(24*60*60));
            app.txt_time_paused.Text = char(duration([0.,0.,paused_time_log]));
            app.txt_status.Text = runstatus;
            app.running_lamp.Color= [0.,1.,0.];
            % logfile
            app.txt_logfile_name.Enable = false;

            % Start log
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'), ...
                newline() + " >>>> Starting run (" + datestr(now) + ")" + newline());

            
            % ---- Start run loop
            %maxtime = 10
            runtime = tic;
            steptime = tic;
            thissteptime = 0;
            stepcounter = 0;
            PrevSteps = [];
            % while toc(runtime) <= maxtime+1
            while size(NextSteps) >= 1
%                 disp(" ------------------------------------------------- ")
%                 disp("Next steps : ")
%                 disp(size(NextSteps))
%                 disp(NextSteps)
                % ---- Advance Step
                % if there is no time step
                if toc(steptime) > thissteptime
                    % ------------------------------------------------------------
                    % disable ending and editing steps
                    app.btn_EndCurrentStep.Enable = false;
                    app.btn_NextStepEdit.Enable = false;
                    % move current to prev
                    PrevSteps = [PrevSteps ; "[" + string(stepcounter) + "] " + app.ta_CurrentStep.Value];
                    app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps(2:end),"\n"));
                    % move first-next to current
                    app.ta_CurrentStep.Value = NextSteps{1};
                    % remove first-next and update
                    NextSteps = NextSteps(2:end);
                    app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));
                    % advance counter
                    stepcounter = stepcounter + 1;

                    % ------------------------------------------------------------
                    % execute current step
                    % flow
                    if contains(upper(app.ta_CurrentStep.Value),"FLOW")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        vflow = str2num(extractAfter(commands{2}, "="));
                        ValveSetFlow(vnumber, vflow, device, tmp_logfiledir);
                    % open
                    elseif contains(upper(app.ta_CurrentStep.Value),"OPEN")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        ValveOpen(vnumber, device, tmp_logfiledir);
                    % close
                    elseif contains(upper(app.ta_CurrentStep.Value),"CLOSE")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        ValveClose(vnumber, device, tmp_logfiledir);
                    % add waiting step
                    elseif contains(upper(app.ta_CurrentStep.Value),"DT")
                        steptime = tic;
                        dtt = extractAfter(app.ta_CurrentStep.Value,'=');
                        thissteptime = str2num(dtt{1})*60;
                        % report
                        fprintf(fopen(tmp_logfiledir,'a'), ...
                            "  <t> Wait time (" + datestr(now)+ "):" + ...
                            " set to wait for " + string(str2num(dtt{1})) + ...
                            "min." + newline());
                        % enable ending and edit steps
                        app.btn_EndCurrentStep.Enable = true;
                        app.btn_NextStepEdit.Enable = true;
                    end
                    % -----------------------------------------------------------
                    
                    
                end 

                % ---- message: total time
                elapsedtime = toc(runtime);
                app.txt_time_elapsed.Text = ...
                    string(duration([0.,0.,elapsedtime])) + ...
                    " / " + string(duration([0.,0.,maxtime])) + ...
                    sprintf(' (%0.1f%%) ',(elapsedtime*100)/maxtime);
                app.gauge_time.Value = (elapsedtime*100)/maxtime;

                % ---- message: step time
                elapsedsteptime = toc(steptime);
                app.txt_time_elapsed_step.Text = ...
                    string(duration([0.,0.,elapsedsteptime])) + ...
                    " / " + string(duration([0.,0.,thissteptime])) + ...
                    sprintf(' (%0.1f%%) ',(elapsedsteptime*100)/thissteptime);


                % ---- wait 1s for next step
                % dynamic pause, considers parsing time
                pause(1-(toc(runtime)-fix(toc(runtime))));
            
            end

            % -----------------------------------------------------------
            % ---- Normal end run
            fprintf(fopen(tmp_logfiledir,'a'),newline() + ...
                " >>>> Ending run (" + datestr(now) + ")" + newline());

            % Restitute app form
            app.btn_EndCurrentStep.Enable = false;
            app.EndrunButton.Enable = false;
            app.btn_pause.Enable = false;

            app.txt_time_end.Text = datestr(now);

            % set valves control mode flow zero
            nvalves = split(app.txt_valves.Value,',');
            for i=1:size(nvalves)
                % Control mode
                ValveClose(str2num(nvalves{i}), device, tmp_logfiledir);
            end
            % Ending log file
            fprintf(fopen(tmp_logfiledir,'a'), ...
                newline() + "Run ended at " + ...
                datestr(now) + "." + newline());

            % End window
            uiwait(msgbox(['Run ended after ', ...
                char(duration([0.,0.,toc(runtime)]))], 'Hey!'));

            app.running_lamp.Color = [.65, .65, .65];
            app.StartButton.Enable = true;
            app.txt_status.Text = 'Setup';
            app.txt_valves.Enable = true;
            app.txa_port.Enable = true;
            app.na_baud.Enable = true;
            app.txt_runname.Enable = true;
            app.btn_connect.Enable = true;
            app.OpenButton.Enable = true;
            app.txt_scriptfile_name.Enable = true;
            app.txt_logfile_name.Enable = true;
            app.btn_NextStepEdit.Enable = true;
            disp(" Run ended succesfuly at " + datestr(now));


        end

        % Button pushed function: btn_pause
        function btn_pauseButtonPushed(app, event)
            %prepare
            global maxtime thissteptime paused_time_log runstatus runtime
            pausetime = tic;
            app.running_lamp.Color = [1., 1., 0.];
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            runstatus = 'Paused';
            app.txt_status.Text = runstatus;
            % report starting pause
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "  <!> Paused from " + datestr(now));
            % actual pause
            uiwait(msgbox(['Paused. No changes to the controllers ' ...
                'will be made while this window is open. Run will resume and paused time will be added ' ...
                'to the runtime when this window is closed.'], 'Paused run'));
            % continue and correct for paused time
            itime = toc(pausetime);
            maxtime = maxtime + itime; % total time correction
            thissteptime = thissteptime + itime; % step time correction
            paused_time_log = paused_time_log + itime; % pause time log
            app.txt_time_paused.Text = char(duration([0.,0.,paused_time_log]));
            app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            % report ending pause
            fprintf(fopen(tmp_logfiledir,'a'), " to " + datestr(now) + ...
                ", t=" + string(duration([0.,0.,paused_time_log])) + newline());
            % status
            app.running_lamp.Color = [0., 1., 0.];
            runstatus = 'Running';
            app.txt_status.Text = runstatus;
            
        end

        % Button pushed function: btn_lognote_add
        function btn_lognote_addButtonPushed(app, event)
            % Add note to current logfile
            global runstatus runtime
            % Check there is a note
            if not(strrep(app.txa_lognote.Value," ","") == "")
                global runtime runstatus
                tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
                tmp_notetext = ['  <!> Note'];
                
                % Add date
                if app.cb_lognote_addtime.Value
                    tmp_notetext = [tmp_notetext ' (' datestr(now)];
                    if not(runstatus=="Setup")
                        tmp_notetext = [tmp_notetext ', t=' char(duration([0.,0.,toc(runtime)]))];
                    end
                    tmp_notetext = [tmp_notetext ')'];
                end                
                tmp_notetext = [tmp_notetext ' : \n'];
                
                % Add
                fprintf(fopen(tmp_logfiledir, 'a'), tmp_notetext);
                fprintf(fopen(tmp_logfiledir, 'a'), "        " + ...
                    strjoin(app.txa_lognote.Value,newline() + ...
                    "        ") + newline());

                % Clear box
                app.txa_lognote.Value = "";
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open
            app.TabGroupLeft.SelectedTab = app.tb_setup;
            scriptfilename = app.txt_scriptfile_name.Value;
            scriptfile = readlines(scriptfilename, 'EmptyLineRule','skip');
            % Parse file
            app.txt_runname.Value = scriptfile{1};
            i=1;
            while i < length(scriptfile)+1
                % Valves declaration
                if contains(upper(scriptfile{i}),"INCLUDE VALVE")
                    valves = str2num(extractAfter(scriptfile{i},':'));
                % baud
                elseif contains(upper(scriptfile{i}),"BAUD")
                    iBaud = str2num(extractAfter(scriptfile{i},'='));
                % port
                elseif contains(upper(scriptfile{i}),"PORT")
                    iPort = strip(extractAfter(scriptfile{i},'='));
                % Start block
                elseif contains(upper(scriptfile{i}),"START")
                    startline = i+1;
                % End line
                elseif contains(upper(scriptfile{i}),"END")
                    endline = i-1;
                end
                i = i+1;
            end
            
            % Create list next steps, clean and parse
            global NextSteps maxtime
            iNextSteps = scriptfile(startline:endline);
            newNextSteps = [];
            for i=1:size(iNextSteps)
                if or (contains(upper(string(iNextSteps{i})),"VALVE"), ...
                    contains(upper(string(iNextSteps{i})),"DT"))
                    newNextSteps = [newNextSteps; string(iNextSteps{i})];
                end
            end
            NextSteps = [newNextSteps; "END LINE"];


            % Summ all time
            itime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    itime = itime + 1 + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    itime = itime + 1;
                end
            end
            maxtime = itime;
            app.txt_time_estimtotal.Text = string(duration([0.,0.,itime]));

            % Report
            app.txt_valves.Value = join(string(valves),','); 
            app.txa_port.Value = iPort;
            app.na_baud.Value = iBaud;
            app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));

            % Update suggested logfile
            app.txt_logfile_name.Value = extractBefore(scriptfilename,".") + "_log";
            

            % Enable start
            app.btn_connect.Enable = true;
        end

        % Button pushed function: btn_connect
        function btn_connectButtonPushed(app, event)
            % Connections
            global device 

            % log part
            disp(app.txt_runname.Value)

            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir, 'a'), string(join(repmat({'*'},1,80),''))+newline());
            fprintf(fopen(tmp_logfiledir, 'a'), "  " + app.txt_runname.Value + newline());
            fprintf(fopen(tmp_logfiledir, 'a'), string(join(repmat({'*'},1,80),''))+newline());


            % connect part
            SerialPort=app.txa_port.Value;
            SerialBaud=app.na_baud.Value;
            ClosePort(SerialPort); % Close first just in case
            device=OpenPort(SerialPort,SerialBaud, [app.txt_logfile_name.Value '.txt']);
            
            % set valves control mode flow zero
            nvalves = split(app.txt_valves.Value,',');
            for i=1:size(nvalves)
                % Control mode
                ValveControl(str2num(nvalves{i}), device, tmp_logfiledir);
                % Set flow to zero
                ValveSetFlow(str2num(nvalves{i}), 0., device, tmp_logfiledir);
            end
            
            % report
            app.txt_logfile_name.Enable = false;
            app.txt_scriptfile_name.Enable = false;
            app.OpenButton.Enable = false;
            app.txa_port.Enable = false;
            app.na_baud.Enable = false;
            app.txt_valves.Enable = false;
            app.txt_runname.Enable = false;
            app.StartButton.Enable = true;
            app.btn_NextStepEdit.Enable = true;
            app.ConnectedLamp.Color = [0., 1., 0.];
            
        end

        % Button pushed function: btn_EndCurrentStep
        function btn_EndCurrentStepButtonPushed(app, event)
            global thissteptime maxtime steptime runtime
            % get time difference that is being jumped
            missingtime = thissteptime - toc(steptime);
            % adjust maxtime
            maxtime = maxtime - missingtime;
            % adjust estimated end time
            app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            % now jump, this step limit is zero
            thissteptime = 0;
            % report jump
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'),"  <!> Jumping step (" + ...
                datestr(now) + ") : current step ended prematurely by " + ...
                "\n      user request after " + ...
                string(duration([0.,0.,toc(steptime)])) + newline());

        end

        % Button pushed function: btn_NextStepEdit
        function btn_NextStepEditButtonPushed(app, event)
            % Edit next steps
            app.ta_NextSteps.Enable = true;
            app.btn_NextStepLoad.Enable = true;
            app.btn_EndCurrentStep.Enable = false;
            app.btn_NextStepEdit.Enable = false;
        end

        % Button pushed function: btn_NextStepLoad
        function btn_NextStepLoadButtonPushed(app, event)
            % Load edited changes to next steps area
            global NextSteps maxtime runtime runstatus
            app.ta_NextSteps.Enable = false;
            app.btn_NextStepLoad.Enable = false;
            app.btn_EndCurrentStep.Enable = true;
            app.btn_NextStepEdit.Enable = true;
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];

            % disp for test
%             disp("Next steps:")
%             disp(NextSteps)
%             disp("textbox:")
%             disp(app.ta_NextSteps.Value)

            % get previous time in NextSteps
            itime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    itime = itime + 1 + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    itime = itime + 1;
                end
            end


            % parse new NextSteps
            newNextSteps = [];
            for i=1:size(app.ta_NextSteps.Value)
                if or (contains(upper(string(app.ta_NextSteps.Value{i})),"VALVE"), ...
                    contains(upper(string(app.ta_NextSteps.Value{i})),"DT"))
                    newNextSteps = [newNextSteps; string(app.ta_NextSteps.Value{i})];
                end
            end
            NextSteps = [newNextSteps; "END LINE"];
            app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));
%             disp("New next:")
%             disp(newNextSteps)

            % get new time in NextSteps
            ntime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    ntime = ntime + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    ntime = ntime + 1;
                end
            end
            % correct time
            maxtime = maxtime - (itime - ntime);
            if runstatus == "Setup"
                app.txt_time_end.Text = "(estim.) " + datestr(now+maxtime/(24*60*60));
            else
                app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            end

            app.txt_time_estimtotal.Text = string(duration([0.,0.,maxtime]));
            % report
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "  <!> Modified the list of the next steps (" + datestr(now) + "), new " + ...
                "\n      left time is " + string(duration([0.,0.,ntime])));
            if not(runstatus == "Setup")
                fprintf(fopen(tmp_logfiledir,'a'), ...
                    ", estimated to end at " + ...
                    datestr(now+(maxtime-toc(runtime))/(24*60*60))+ newline());
            else
                fprintf(fopen(tmp_logfiledir,'a'), newline());
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {370, 370};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {374, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EndrunButton = uibutton(app.LeftPanel, 'push');
            app.EndrunButton.ButtonPushedFcn = createCallbackFcn(app, @EndrunButtonPushed, true);
            app.EndrunButton.Enable = 'off';
            app.EndrunButton.Text = 'End run';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.running_lamp = uilamp(app.LeftPanel);
            app.StartLabel_3 = uilabel(app.LeftPanel);
            app.StartLabel_3.Text = 'Log file name';
            app.txt_logfile_name = uieditfield(app.LeftPanel, 'text');
            app.txt_logfile_name.Value = 'logfile';
            app.StartLabel_4 = uilabel(app.LeftPanel);
            app.StartLabel_4.Text = 'Script file name';
            app.txt_scriptfile_name = uieditfield(app.LeftPanel, 'text');
            app.txt_scriptfile_name.Value = 'script.txt';
            app.btn_pause = uibutton(app.LeftPanel, 'push');
            app.btn_pause.ButtonPushedFcn = createCallbackFcn(app, @btn_pauseButtonPushed, true);
            app.btn_pause.Enable = 'off';
            app.btn_pause.Text = 'Pause';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.txt_status = uilabel(app.LeftPanel);
            app.txt_status.Text = 'Setup';
            app.TabGroupLeft = uitabgroup(app.LeftPanel);
            app.tb_setup = uitab(app.TabGroupLeft);
            app.tb_setup.Title = 'Setup';
            app.btn_connect = uibutton(app.tb_setup, 'push');
            app.btn_connect.ButtonPushedFcn = createCallbackFcn(app, @btn_connectButtonPushed, true);
            app.btn_connect.Enable = 'off';
            app.btn_connect.Text = 'Connect & log';
            app.PortEditFieldLabel = uilabel(app.tb_setup);
            app.PortEditFieldLabel.Text = 'Port : ';
            app.txa_port = uieditfield(app.tb_setup, 'text');
            app.BaudrateEditFieldLabel = uilabel(app.tb_setup);
            app.BaudrateEditFieldLabel.Text = 'Baud rate : ';
            app.na_baud = uieditfield(app.tb_setup, 'numeric');
            app.txt_time_estimtotal = uilabel(app.tb_setup);
            app.txt_time_estimtotal.Text = '';
            app.EstimatedtotaltimeLabel = uilabel(app.tb_setup);
            app.EstimatedtotaltimeLabel.Text = 'Estimated total time : ';
            app.RunnameLabel = uilabel(app.tb_setup);
            app.RunnameLabel.Text = 'Run name : ';
            app.txt_runname = uieditfield(app.tb_setup, 'text');
            app.ConnectedLampLabel = uilabel(app.tb_setup);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.tb_setup);
            app.ValuesLabel = uilabel(app.tb_setup);
            app.ValuesLabel.Text = 'Values : ';
            app.txt_valves = uieditfield(app.tb_setup, 'text');
            app.tb_runtime = uitab(app.TabGroupLeft);
            app.tb_runtime.Title = 'Run time information';
            app.StarttimeLabel = uilabel(app.tb_runtime);
            app.StarttimeLabel.Text = 'Start time : ';
            app.txt_time_start = uilabel(app.tb_runtime);
            app.txt_time_start.Text = {''; ''};
            app.txt_time_end = uilabel(app.tb_runtime);
            app.txt_time_end.Text = '';
            app.EndtimeLabel = uilabel(app.tb_runtime);
            app.EndtimeLabel.Text = 'End time : ';
            app.txt_time_paused = uilabel(app.tb_runtime);
            app.txt_time_paused.Text = '';
            app.PausedtimeLabel = uilabel(app.tb_runtime);
            app.PausedtimeLabel.Text = 'Paused time : ';
            app.txt_time_elapsed = uilabel(app.tb_runtime);
            app.txt_time_elapsed.Text = '';
            app.totalLabel = uilabel(app.tb_runtime);
            app.totalLabel.Text = 'total : ';
            app.gauge_time = uigauge(app.tb_runtime, 'linear');
            app.gauge_time.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.txt_time_elapsed_step = uilabel(app.tb_runtime);
            app.txt_time_elapsed_step.Text = '';
            app.ElapsedtimestepLabel = uilabel(app.tb_runtime);
            app.ElapsedtimestepLabel.Text = 'Elapsed time step : ';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.next_steps = uitab(app.TabGroup);
            app.next_steps.Title = 'Steps';
            app.btn_NextStepEdit = uibutton(app.next_steps, 'push');
            app.btn_NextStepEdit.ButtonPushedFcn = createCallbackFcn(app, @btn_NextStepEditButtonPushed, true);
            app.btn_NextStepEdit.Text = 'Edit';
            app.ta_NextSteps = uitextarea(app.next_steps);
            app.ta_NextSteps.Enable = 'off';
            app.btn_EndCurrentStep = uibutton(app.next_steps, 'push');
            app.btn_EndCurrentStep.ButtonPushedFcn = createCallbackFcn(app, @btn_EndCurrentStepButtonPushed, true);
            app.btn_EndCurrentStep.Enable = 'off';
            app.btn_EndCurrentStep.Text = 'End Current step';
            app.ta_CurrentStep = uitextarea(app.next_steps);
            app.ta_CurrentStep.Editable = 'off';
            app.ta_CurrentStep.Enable = 'off';
            app.CurrentLabel = uilabel(app.next_steps);
            app.CurrentLabel.Text = 'Current : ';
            app.NextLabel = uilabel(app.next_steps);
            app.NextLabel.Enable = 'off';
            app.NextLabel.Text = 'Next : ';
            app.btn_NextStepLoad = uibutton(app.next_steps, 'push');
            app.btn_NextStepLoad.ButtonPushedFcn = createCallbackFcn(app, @btn_NextStepLoadButtonPushed, true);
            app.btn_NextStepLoad.Enable = 'off';
            app.btn_NextStepLoad.Text = 'Load';
            app.prev_steps = uitab(app.TabGroup);
            app.prev_steps.Title = 'Previous steps';
            app.ta_PrevSteps = uitextarea(app.prev_steps);
            app.ta_PrevSteps.Editable = 'off';
            app.ta_PrevSteps.Enable = 'off';
            app.AddlognotesTab = uitab(app.TabGroup);
            app.AddlognotesTab.Title = 'Add log notes';
            app.CommentTextAreaLabel = uilabel(app.AddlognotesTab);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.txa_lognote = uitextarea(app.AddlognotesTab);
            app.cb_lognote_addtime = uicheckbox(app.AddlognotesTab);
            app.cb_lognote_addtime.Text = 'Add time';
            app.cb_lognote_addtime.Value = true;
            app.btn_lognote_add = uibutton(app.AddlognotesTab, 'push');
            app.btn_lognote_add.ButtonPushedFcn = createCallbackFcn(app, @btn_lognote_addButtonPushed, true);
            app.btn_lognote_add.Text = 'add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end